classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OutputEditField_2          matlab.ui.control.EditField
        OutputEditField_2Label     matlab.ui.control.Label
        OutputEditField            matlab.ui.control.EditField
        OutputEditFieldLabel       matlab.ui.control.Label
        DecipherButton             matlab.ui.control.Button
        KeywordEditField_2         matlab.ui.control.EditField
        KeywordEditField_2Label    matlab.ui.control.Label
        TypeaCipherEditField       matlab.ui.control.EditField
        TypeaCipherEditFieldLabel  matlab.ui.control.Label
        KeywordEditField           matlab.ui.control.EditField
        KeywordEditFieldLabel      matlab.ui.control.Label
        EncipherButton             matlab.ui.control.Button
        TypeawordEditField         matlab.ui.control.EditField
        PleaseinputawordLabel      matlab.ui.control.Label
        DropDown_2                 matlab.ui.control.DropDown
        DropDown_2Label            matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        Image                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown, DropDown_2
        function DropDownValueChanged(app, event)
            
            
        end

        % Callback function
        function DropDownOpening(app, event)
            
        end

        % Callback function
        function DropDownClicked(app, event)
            
        end

        % Callback function
        function DropDown_2Clicked(app, event)
           
            
        end

        % Button pushed function: EncipherButton
        function EncipherButtonPushed(app, event)
            if strcmp(app.DropDown.Value,'Caesar Cipher')
            text2encode = app.TypeawordEditField.Value;
            keyword = app.KeywordEditField.Value;
            app.OutputEditField.Value = CaesarCipher(text2encode,keyword);
            app.OutputEditFieldLabel.Text = app.DropDown.Value;

            elseif strcmp(app.DropDown.Value,'Vigenere Cipher')
           text2encode = app.TypeawordEditField.Value;
           keyword = app.KeywordEditField.Value;
           app.OutputEditField.Value = vigenere_cipher(text2encode,keyword);
           app.OutputEditFieldLabel.Text = app.DropDown.Value;

            elseif strcmp(app.DropDown.Value,'Columnar Transposition')
                text2encode = app.TypeawordEditField.Value;
                keyword = app.KeywordEditField.Value;
                app.OutputEditField.Value= col_trans(keyword,text2encode);
                app.OutputEditFieldLabel.Text= app.DropDown.Value;
            end

            
        end

        % Button pushed function: DecipherButton
        function DecipherButtonPushed(app, event)
            if strcmp(app.DropDown_2.Value,'Caesar Cipher')
            text2decode = app.TypeaCipherEditField.Value;
            keyword=app.KeywordEditField_2.Value;
            app.OutputEditField_2.Value= Decipher(text2decode,keyword);
            app.OutputEditField_2Label.Text = app.DropDown_2.Value;

            elseif strcmp(app.DropDown_2.Value, 'Vigenere Cipher')
                text2decode = app.TypeaCipherEditField.Value;
                keyword=app.KeywordEditField_2.Value;
                app.OutputEditField_2.Value = vigenere_decipher(text2decode,keyword);
                app.OutputEditField_2Label.Text = app.DropDown_2.Value;

            elseif strcmp(app.DropDown_2.Value,'Columnar Transposition')
            text2decode = app.TypeaCipherEditField.Value;
            keyword = app.KeywordEditField_2.Value;
            app.OutputEditField_2.Value = revcol_trans(keyword,text2decode);
            app.OutputEditField_2Label.Text = app.DropDown_2.Value;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'password-encryption-wallpaper-preview.jpg');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Choose a Cipher!', 'Vigenere Cipher', 'Caesar Cipher', 'Columnar Transposition'};
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Choose a Cipher!';
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'Click to Decipher!', 'Vigenere Cipher', 'Caesar Cipher', 'Columnar Transposition'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown_2.Value = 'Click to Decipher!';
            app.PleaseinputawordLabel = uilabel(app.UIFigure);
            app.PleaseinputawordLabel.Text = 'Type a word!';
            app.TypeawordEditField = uieditfield(app.UIFigure, 'text');
            app.EncipherButton = uibutton(app.UIFigure, 'push');
            app.EncipherButton.ButtonPushedFcn = createCallbackFcn(app, @EncipherButtonPushed, true);
            app.EncipherButton.Text = 'Encipher';
            app.KeywordEditFieldLabel = uilabel(app.UIFigure);
            app.KeywordEditFieldLabel.Text = 'Keyword?';
            app.KeywordEditField = uieditfield(app.UIFigure, 'text');
            app.TypeaCipherEditFieldLabel = uilabel(app.UIFigure);
            app.TypeaCipherEditFieldLabel.Text = 'Type a Cipher!';
            app.TypeaCipherEditField = uieditfield(app.UIFigure, 'text');
            app.KeywordEditField_2Label = uilabel(app.UIFigure);
            app.KeywordEditField_2Label.Text = 'Keyword?';
            app.KeywordEditField_2 = uieditfield(app.UIFigure, 'text');
            app.DecipherButton = uibutton(app.UIFigure, 'push');
            app.DecipherButton.ButtonPushedFcn = createCallbackFcn(app, @DecipherButtonPushed, true);
            app.DecipherButton.Text = 'Decipher';
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.OutputEditField_2Label = uilabel(app.UIFigure);
            app.OutputEditField_2Label.Text = 'Output';
            app.OutputEditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end